classdef unitconversion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ConvertButton           matlab.ui.control.Button
        UnitConversionAppLabel  matlab.ui.control.Label
        DefaultButton           matlab.ui.control.Button
        NumberEditFieldLabel    matlab.ui.control.Label
        Number                  matlab.ui.control.NumericEditField
        UnitDropDownLabel       matlab.ui.control.Label
        UnitDropDown            matlab.ui.control.DropDown
        ResultEditFieldLabel    matlab.ui.control.Label
        Result                  matlab.ui.control.NumericEditField
        WarningEditFieldLabel   matlab.ui.control.Label
        WarningEditField        matlab.ui.control.EditField
    end

    
    properties (Access = public)
        
      
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UnitDropDown.Items={'<Click to choose>','Temperature','Length and distance','Mass'}; % Initialize the drop down menu
            app.Number.Value=0;
            app.Result.Value=0;
            app.WarningEditField.Value='';
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
          input1=app.UnitDropDown.Value; % sunstitute variable to callback function
          input2=app.Number.Value; % sunstitute variable to callback function
          output = convertunit(input1,input2); %user function
          app.Result.Value=output; % sunstitute variable to callback function
          if strcmp(input1,'celcius to fahrenheit') || strcmp(input1,'fahrenheit to celcius')
              app.WarningEditField.Value='No warning'; %Put warning message
          elseif output>0 &&~strcmp(input1,'celcius to fahrenheit') || strcmp(input1,'fahrenheit to celcius')
              app.WarningEditField.Value='No warning'; %Put warning message
          else
              app.WarningEditField.Value='Invalid Result';
          end
        end

        % Value changed function: UnitDropDown
        function UnitDropDownValueChanged(app, event)
            value = app.UnitDropDown.Value;
            switch value
                case 'Temperature'
                    app.UnitDropDown.Items={'celcius to fahrenheit','fahrenheit to celcius'};
                case 'Length and distance'
                    app.UnitDropDown.Items={'centimeters to inch','inch to centimeters','meters to feet','feet to meters','kilometers to miles','miles to kilometers'};
                case 'Mass'
                    app.UnitDropDown.Items={'grams to ounces','ounces to grams','kilograms to pounds','pounds to kilograms','tonne to ton','ton to tonne'};
            end
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            startupFcn(app) %%[1] restart app to default setting
            %%[1]"restart an app with code - MATLAB Answers - MATLAB Central", Ww2.mathworks.cn, 2020. [Online]. Available: https://ww2.mathworks.cn/matlabcentral/answers/469588-restart-an-app-with-code?#answer_381422. [Accessed: 07- Aug- 2020].
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConvertButton = uibutton(app.UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.UnitConversionAppLabel = uilabel(app.UIFigure);
            app.UnitConversionAppLabel.Text = 'Unit Conversion App';
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.NumberEditFieldLabel = uilabel(app.UIFigure);
            app.NumberEditFieldLabel.Text = 'Number';
            app.Number = uieditfield(app.UIFigure, 'numeric');
            app.UnitDropDownLabel = uilabel(app.UIFigure);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.UIFigure);
            app.UnitDropDown.Items = {'<Click to choose>', 'Temperature', 'Length and distance', 'Mass'};
            app.UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitDropDownValueChanged, true);
            app.UnitDropDown.Value = '<Click to choose>';
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.Result = uieditfield(app.UIFigure, 'numeric');
            app.WarningEditFieldLabel = uilabel(app.UIFigure);
            app.WarningEditFieldLabel.Text = 'Warning';
            app.WarningEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = unitconversion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end